classdef RobotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes_3  matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        masterRobot = struct('robot', [], 'plotter', []);
        slaveRobot  = struct('robot', [], 'plotter', []);
        usingMouse  = false; % default using mouse mode
        xm_des;
        ym_des;
    end
    
    methods (Access = public)
       
        function um = master_controller(app, t, qm, dqm)
            M = app.masterRobot.robot.get_mass_matrix(qm);
            C = app.masterRobot.robot.get_coriolis(qm, dqm);
            G = app.masterRobot.robot.get_graviry(qm);

            if app.usingMouse
                qm_des    = app.masterRobot.robot.solve_ikine(app.xm_des, app.ym_des);
                dqm_des   = zeros(2, 1);
                ddqm_des  = zeros(2, 1);
            else
                desireds = app.get_master_traj(t);
                qm_des   = desireds.q;
                dqm_des  = desireds.dq;
                ddqm_des = desireds.ddq;
            end

            e  = qm_des  - qm;
            de = dqm_des - dqm;

            k1 = eye(2);
            k2 = eye(2);

            v = ddqm_des + k1 * de + k2 * e;
            um = M * v + C * dqm + G;
        end

        function us = slave_controller(app, qs, dqs, qm, dqm, ddqm)
            M = app.masterRobot.robot.get_mass_matrix(qs);
            C = app.masterRobot.robot.get_coriolis(qs, dqs);
            G = app.masterRobot.robot.get_graviry(qs);

            e  = qm  - qs;
            de = dqm - dqs;

            k1 = eye(2);
            k2 = eye(2);

            v = ddqm + k1 * de + k2 * e;
            us = M * v + C * dqs + G;
        end

        function des = get_master_traj(~, t)
            des.q   = [ sin(t);  cos(t)];
            des.dq  = [ cos(t); -sin(t)];
            des.ddq = [-sin(t); -cos(t)];
        end

        function [right_fig, left_fig] = draw_robots(app, t, qm, qs)
            right_fig = app.UIAxes;
            right_fig = app.masterRobot.plotter.get_line_hand(right_fig, qm, ...
                'Color', [1, 0, 0], 'LineWidth', 5);

            left_fig = app.UIAxes_3;
            left_fig = app.slaveRobot.plotter.get_line_hand(left_fig, qs, ...
                'Color', [0, 1, 0], 'LineWidth', 5);

            Xm = app.masterRobot.robot.get_ee_pos(qm);
            Xs = app.masterRobot.robot.get_ee_pos(qs);
            down_fig = app.UIAxes2;
            plot(down_fig, t, Xm - Xs, 'k.', 'MarkerSize', 5)
            hold(down_fig, 'on')
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, masterRobot, slaveRobot, usingMouse)
            app.masterRobot.robot = masterRobot.robot;
            app.masterRobot.plotter = masterRobot.plotter;
            app.UIAxes.XLim = 2.5 * [-1, 1];
            app.UIAxes.YLim = 2.5 * [-1, 1];

            app.slaveRobot.robot = slaveRobot.robot;
            app.slaveRobot.plotter = slaveRobot.plotter;
            app.UIAxes_3.XLim = 2.5 * [-1, 1];
            app.UIAxes_3.YLim = 2.5 * [-1, 1];

            app.UIAxes2.XLim = 20 * [0 , 1];
            app.UIAxes2.YLim = 2  * [-1, 1];

            set(0, 'defaultTextInterpreter', 'latex');

            app.usingMouse = usingMouse;
            if usingMouse
                figure
                input_gca = gca;
                input_gca.XLim = [-2, 2];
                input_gca.YLim = [-2, 2];
                theta = linspace(0, 2*pi, 100);
                r = app.masterRobot.robot.l1 + app.masterRobot.robot.l2;
                x = r * cos(theta);
                y = r * sin(theta);
                plot(input_gca, x, y, 'r.')
                grid(input_gca, 'minor')
                setUpTitle = sprintf(['Setting the Master Robot Desires \n' ...
                    'Please Insert Desired X and Y Inside the Task-Space']);
                title(input_gca, setUpTitle)
                xlabel(input_gca, 'X')
                ylabel(input_gca, 'Y')
                legend(input_gca, 'Task-Space')
                axis(input_gca, 'equal')
                [app.xm_des, app.ym_des] = ginput(1);
            end
            
        end

        % Button down function: UIAxes, UIAxes_3
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Master Robot', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Slave Robot')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Real-Time Tracking Error', 'Interpreter', 'latex')
            xlabel(app.UIAxes2, 't [sec]', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, 'e(t)', 'Interpreter', 'latex')
            zlabel(app.UIAxes2, 'Z', 'Interpreter', 'latex')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end